classdef centerline_extractor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CenterlineExtractorV10UIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        AlgorithmDropDown               matlab.ui.control.DropDown
        AlgorithmDropDownLabel          matlab.ui.control.Label
        KeepeverynthpointEditField      matlab.ui.control.NumericEditField
        KeepeverynthpointEditFieldLabel  matlab.ui.control.Label
        GrowthFactorEditField           matlab.ui.control.NumericEditField
        GrowthFactorEditFieldLabel      matlab.ui.control.Label
        IterationsEditField             matlab.ui.control.NumericEditField
        IterationsEditFieldLabel        matlab.ui.control.Label
        SaveasmatButton                 matlab.ui.control.Button
        PreviewSendtoCoilDesignerButton  matlab.ui.control.Button
        PostprocessingParametersLabel   matlab.ui.control.Label
        ExtractionParametersLabel       matlab.ui.control.Label
        LoadSTLButton                   matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        STLTab                          matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        CenterlineTab                   matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        TR % Triangulation object
        Pcenter % Extracted centerline
        SavePath;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            s = pwd; addpath([s, '\Engine']);
            s = pwd; addpath([s, '\GUI']);
            s1 = pwd; addpath([s1, '\CenterlineExtraction']);
        end

        % Button pushed function: LoadSTLButton
        function LoadSTLButtonPushed(app, event)
            [file,location] = uigetfile('*.stl');
            STLPath = fullfile(location,file);
            app.TR = stlread(STLPath);
            app.TabGroup.SelectedTab = app.STLTab;
            cla(app.UIAxes);
            display_CADmodel_GUI(app.UIAxes, app.TR.Points, app.TR.ConnectivityList, 0, 11, 31, 1);
        end

        % Button pushed function: PreviewSendtoCoilDesignerButton
        function PreviewSendtoCoilDesignerButtonPushed(app, event)
            algorithm = app.AlgorithmDropDown.Value;
            max_iterations = app.IterationsEditField.Value;
            sL = app.GrowthFactorEditField.Value;
            n = app.KeepeverynthpointEditField.Value;
            app.Pcenter = Extraction_GUI(app.TR,max_iterations,sL,n,algorithm);
            app.TabGroup.SelectedTab = app.CenterlineTab;
            cla(app.UIAxes2);
            display_centerline_GUI(app.UIAxes2,app.Pcenter);
            hold(app.UIAxes2, 'on')
            display_CADmodel_GUI(app.UIAxes2, app.TR.Points, app.TR.ConnectivityList, 0, 11, 31, 0.3);
            tempPath = fullfile(pwd,'\temp','Pcenter');
            if ~exist(tempPath,'dir')
                mkdir(tempPath);
            end
            P = app.Pcenter;
            save(tempPath, 'P');
        end

        % Button pushed function: SaveasmatButton
        function SaveasmatButtonPushed(app, event)
            app.SavePath = uigetdir; 
            if ~exist(fullfile(app.SavePath, 'Output'), 'dir')
                mkdir(fullfile(app.SavePath, 'Output'));
            end
            p = app.Pcenter;
            save(fullfile(app.SavePath, 'Pcenter'), 'p');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.CenterlineExtractorV10UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {667, 667};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {221, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CenterlineExtractorV10UIFigure = uifigure('Visible', 'off');
            app.CenterlineExtractorV10UIFigure.Name = 'Centerline Extractor V1.0';
            app.CenterlineExtractorV10UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.CenterlineExtractorV10UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.LoadSTLButton = uibutton(app.GridLayout2, 'push');
            app.LoadSTLButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSTLButtonPushed, true);
            app.LoadSTLButton.Text = 'Load STL';
            app.ExtractionParametersLabel = uilabel(app.GridLayout2);
            app.ExtractionParametersLabel.Text = 'Extraction Parameters:';
            app.PostprocessingParametersLabel = uilabel(app.GridLayout2);
            app.PostprocessingParametersLabel.Text = 'Post-processing  Parameters:';
            app.PreviewSendtoCoilDesignerButton = uibutton(app.GridLayout2, 'push');
            app.PreviewSendtoCoilDesignerButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewSendtoCoilDesignerButtonPushed, true);
            app.PreviewSendtoCoilDesignerButton.Text = 'Preview & Send to Coil Designer';
            app.SaveasmatButton = uibutton(app.GridLayout2, 'push');
            app.SaveasmatButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasmatButtonPushed, true);
            app.SaveasmatButton.Text = 'Save as .mat';
            app.IterationsEditFieldLabel = uilabel(app.GridLayout2);
            app.IterationsEditFieldLabel.Text = 'Iterations:';
            app.IterationsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.IterationsEditField.Limits = [2 30];
            app.IterationsEditField.RoundFractionalValues = 'on';
            app.IterationsEditField.Value = 11;
            app.GrowthFactorEditFieldLabel = uilabel(app.GridLayout2);
            app.GrowthFactorEditFieldLabel.Text = 'Growth Factor:';
            app.GrowthFactorEditField = uieditfield(app.GridLayout2, 'numeric');
            app.GrowthFactorEditField.Limits = [2 12];
            app.GrowthFactorEditField.RoundFractionalValues = 'on';
            app.GrowthFactorEditField.Value = 8;
            app.KeepeverynthpointEditFieldLabel = uilabel(app.GridLayout2);
            app.KeepeverynthpointEditFieldLabel.Text = 'Keep every n-th point:';
            app.KeepeverynthpointEditField = uieditfield(app.GridLayout2, 'numeric');
            app.KeepeverynthpointEditField.Limits = [1 30];
            app.KeepeverynthpointEditField.RoundFractionalValues = 'on';
            app.KeepeverynthpointEditField.Value = 10;
            app.AlgorithmDropDownLabel = uilabel(app.GridLayout2);
            app.AlgorithmDropDownLabel.Text = 'Algorithm:';
            app.AlgorithmDropDown = uidropdown(app.GridLayout2);
            app.AlgorithmDropDown.Items = {'Nearest neighbor rearranging', 'No rearranging'};
            app.AlgorithmDropDown.Value = 'Nearest neighbor rearranging';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.STLTab = uitab(app.TabGroup);
            app.STLTab.Title = 'STL';
            app.UIAxes = uiaxes(app.STLTab);
            title(app.UIAxes, 'Imported STL')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CenterlineTab = uitab(app.TabGroup);
            app.CenterlineTab.Title = 'Centerline';
            app.UIAxes2 = uiaxes(app.CenterlineTab);
            title(app.UIAxes2, 'Centerline Preview')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.CenterlineExtractorV10UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = centerline_extractor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CenterlineExtractorV10UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CenterlineExtractorV10UIFigure)
        end
    end
end