classdef coil_designer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CoilDesignerV09UIFigure       matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        TabGroup2                     matlab.ui.container.TabGroup
        CoilParameterTab              matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        ZEditField                    matlab.ui.control.NumericEditField
        ZEditFieldLabel               matlab.ui.control.Label
        YEditField                    matlab.ui.control.NumericEditField
        YEditFieldLabel               matlab.ui.control.Label
        XEditField                    matlab.ui.control.NumericEditField
        XEditFieldLabel               matlab.ui.control.Label
        ArrayweightsLabel             matlab.ui.control.Label
        LoadButton                    matlab.ui.control.Button
        FrommatLabel                  matlab.ui.control.Label
        FromSTLLabel                  matlab.ui.control.Label
        CenterlineLabel               matlab.ui.control.Label
        LaunchWizardButton            matlab.ui.control.Button
        bmEditField                   matlab.ui.control.NumericEditField
        bmLabel                       matlab.ui.control.Label
        amEditField                   matlab.ui.control.NumericEditField
        amEditFieldLabel              matlab.ui.control.Label
        ShapeDropDown                 matlab.ui.control.DropDown
        ShapeDropDownLabel            matlab.ui.control.Label
        TurnsEditField                matlab.ui.control.NumericEditField
        TurnsLabel                    matlab.ui.control.Label
        PitchmEditField               matlab.ui.control.NumericEditField
        PitchmLabel                   matlab.ui.control.Label
        b0mEditField                  matlab.ui.control.NumericEditField
        b0mEditFieldLabel             matlab.ui.control.Label
        RadiusmEditField              matlab.ui.control.NumericEditField
        RadiusmEditFieldLabel         matlab.ui.control.Label
        a0mEditField                  matlab.ui.control.NumericEditField
        a0mEditFieldLabel             matlab.ui.control.Label
        CoiltypeDropDown              matlab.ui.control.DropDown
        CoiltypeLabel                 matlab.ui.control.Label
        PreviewButton                 matlab.ui.control.Button
        CrosssectionLabel             matlab.ui.control.Label
        PlacementTab                  matlab.ui.container.Tab
        GridLayout3                   matlab.ui.container.GridLayout
        DeleteSphereButton            matlab.ui.control.Button
        AddSpheretocanvasButton       matlab.ui.control.Button
        RadiusmEditField_2            matlab.ui.control.NumericEditField
        RadiusmEditField_2Label       matlab.ui.control.Label
        zmEditField_2                 matlab.ui.control.NumericEditField
        zmEditField_2Label            matlab.ui.control.Label
        ymEditField_2                 matlab.ui.control.NumericEditField
        ymEditField_2Label            matlab.ui.control.Label
        xmEditField_2                 matlab.ui.control.NumericEditField
        xmEditField_2Label            matlab.ui.control.Label
        SphereLabel                   matlab.ui.control.Label
        ThecoildisplayedinpreviewwillbeaddedtothecanvasLabel  matlab.ui.control.Label
        CoilexcitationLabel           matlab.ui.control.Label
        CoilOrientationLabel          matlab.ui.control.Label
        CoilPositionLabel             matlab.ui.control.Label
        DeleteButton                  matlab.ui.control.Button
        SelectCoilDropDown            matlab.ui.control.DropDown
        SelectCoilDropDownLabel       matlab.ui.control.Label
        ClearallcoilsButton           matlab.ui.control.Button
        CoilIDEditField               matlab.ui.control.EditField
        LabelEditFieldLabel           matlab.ui.control.Label
        dIdTAsecEditField             matlab.ui.control.NumericEditField
        dIdTAsecEditFieldLabel        matlab.ui.control.Label
        CurrentAEditField             matlab.ui.control.NumericEditField
        CurrentAEditFieldLabel        matlab.ui.control.Label
        zrotationdegEditField         matlab.ui.control.NumericEditField
        zrotationdegEditFieldLabel    matlab.ui.control.Label
        yrotationdegEditField         matlab.ui.control.NumericEditField
        yrotationdegEditFieldLabel    matlab.ui.control.Label
        xrotationdegEditField         matlab.ui.control.NumericEditField
        xrotationdegEditFieldLabel    matlab.ui.control.Label
        zmEditField                   matlab.ui.control.NumericEditField
        zmEditFieldLabel              matlab.ui.control.Label
        ymEditField                   matlab.ui.control.NumericEditField
        ymEditFieldLabel              matlab.ui.control.Label
        xmEditField                   matlab.ui.control.NumericEditField
        xmEditFieldLabel              matlab.ui.control.Label
        AddcoiltocanvasButton         matlab.ui.control.Button
        SimulationTab                 matlab.ui.container.Tab
        GridLayout4                   matlab.ui.container.GridLayout
        YouwillbepromptedtosaveLabel  matlab.ui.control.Label
        PlottypeDropDown              matlab.ui.control.DropDown
        PlottypeDropDownLabel         matlab.ui.control.Label
        FieldComponentDropDown        matlab.ui.control.DropDown
        FieldComponentDropDownLabel   matlab.ui.control.Label
        mLabel                        matlab.ui.control.Label
        CurrentlayerSpinner           matlab.ui.control.Spinner
        CurrentlayerSpinnerLabel      matlab.ui.control.Label
        ObservationregiondiscretizationLabel  matlab.ui.control.Label
        ObservationregiondimensionsLabel  matlab.ui.control.Label
        Closethresholdm0fornotremovingsingularitiesEditField  matlab.ui.control.NumericEditField
        Closethresholdm0fornotremovingsingularitiesEditFieldLabel  matlab.ui.control.Label
        LayerSlider                   matlab.ui.control.Slider
        LayerSliderLabel              matlab.ui.control.Label
        VectorSwitch                  matlab.ui.control.Switch
        VectorLabel                   matlab.ui.control.Label
        CoiloverlaySwitch             matlab.ui.control.Switch
        CoiloverlaySwitchLabel        matlab.ui.control.Label
        ZoffsetEditField              matlab.ui.control.NumericEditField
        ZoffsetEditFieldLabel         matlab.ui.control.Label
        YoffsetEditField              matlab.ui.control.NumericEditField
        YoffsetEditFieldLabel         matlab.ui.control.Label
        XoffsetEditField              matlab.ui.control.NumericEditField
        XoffsetEditFieldLabel         matlab.ui.control.Label
        YgridsEditField               matlab.ui.control.NumericEditField
        YgridsEditFieldLabel          matlab.ui.control.Label
        XgridsEditField               matlab.ui.control.NumericEditField
        XgridsEditFieldLabel          matlab.ui.control.Label
        SlicealongDropDown            matlab.ui.control.DropDown
        SlicealongDropDownLabel       matlab.ui.control.Label
        ZgridsEditField               matlab.ui.control.NumericEditField
        ZgridsEditFieldLabel          matlab.ui.control.Label
        zsizemEditField               matlab.ui.control.NumericEditField
        zsizemEditFieldLabel          matlab.ui.control.Label
        ysizemEditField               matlab.ui.control.NumericEditField
        ysizemEditFieldLabel          matlab.ui.control.Label
        xsizemEditField               matlab.ui.control.NumericEditField
        xsizemEditFieldLabel          matlab.ui.control.Label
        ComputefieldsButton           matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        BusyLamp                      matlab.ui.control.Lamp
        BusyLampLabel                 matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        PreviewTab                    matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        CanvasTab                     matlab.ui.container.Tab
        UIAxes2_2                     matlab.ui.control.UIAxes
        ETab                          matlab.ui.container.Tab
        UIAxes3_2                     matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        BTab                          matlab.ui.container.Tab
        UIAxes4_2                     matlab.ui.control.UIAxes
        UIAxes4                       matlab.ui.control.UIAxes
        InductanceTab                 matlab.ui.container.Tab
        InductancematrixHLabel        matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        ComputeInductanceButton       matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        strcoil % Coil
        coilgroup % A set of coils added to the canvas
        points % Points where fields are calculated
        Emag % E field magnitude
        Bmag % B field magnitude
        Evx    % E field vector, X component in 3D
        Evy    % E field vector, Y component in 3D
        Evz    % E field vector, Z component in 3D
        Bvx    % B field vector, X component in 3D
        Bvy    % B field vector, Y component in 3D
        Bvz    % B field vector, Z component in 3D
        x   % x points array
        y   % y points array
        z   % z points array
        Location % Saving directory
        PcenterPath  % Centerline file path
        displayedE   % E field to be displayed (2D filled)
        displayedB   % B field to be displayed (2D filled)
        titleE   % E field plot title (2D filled)
        titleB   % B field plot title (2D filled)
        arrayPositions % Struct that records how each coil was moved by the user
        indMat % Induction matrix
        S %  Sphere
        arr_flag %array flag
        ind_group %array for computing inductance
    end
    
    methods (Access = private)
        
        function [] = delete_helper(app)
            app.AddcoiltocanvasButton.Enable = 'on';
            coil_label = app.SelectCoilDropDown.Value;
            coils = app.coilgroup;
            if ~contains(coil_label, 'New Coil')
                % Find coil
                for coil_idx = 1:length(coils)
                    entry = coils(coil_idx).label;
                    if strcmp(entry, coil_label)
                        app.coilgroup(coil_idx) = [];
                        break
                    end
                end
    
                % Update graphics
                cla(app.UIAxes2_2);
                display_CADmodel_Array(app.UIAxes2_2, app.coilgroup, 0, 11, 31, 1);
    
                % Update drop-downs
                [~,idx] = ismember(coil_label,app.SelectCoilDropDown.Items);
                app.SelectCoilDropDown.Items(idx) = [];
                app.CoilIDEditField.Value = string(length(app.coilgroup));
            end
        end

        function [displayedE, displayedB, titleE, titleB] = contour_display_helper(app, axis, component)
            switch component 
                case 'X'
                    displayedE = app.Evx;
                    displayedB = app.Bvx;
                    titleE1 = 'E-field vector x component ';
                    titleB1 = 'B-field vector x component ';
                case 'Y'
                    displayedE = app.Evy;
                    displayedB = app.Bvy;
                    titleE1 = 'E-field vector y component ';
                    titleB1 = 'B-field vector y component ';
                case 'Z'
                    displayedE = app.Evz;
                    displayedB = app.Bvz;
                    titleE1 = 'E-field vector z component ';
                    titleB1 = 'B-field vector z component ';
                otherwise
                    displayedE = app.Emag;
                    displayedB = app.Bmag;
                    titleE1 = 'E-field magnitude ';
                    titleB1 = 'B-field magnitude ';
            end
            switch axis
                case 'X'
                    titleE = join([titleE1, 'in the YZ plane (V/m)']);
                    titleB = join([titleB1, 'in the YZ plane (T)']);
                case 'Y'
                    titleE = join([titleE1, 'in the XZ plane (V/m)']);
                    titleB = join([titleB1, 'in the XZ plane (T)']);
                case 'Z'
                    titleE = join([titleE1, 'in the XY plane (V/m)']);
                    titleB = join([titleB1, 'in the XY plane (T)']);
            end
                    
        end
        
        function [displayedE, displayedB, titleE, titleB] = Sphere_display_helper(app, component, Ex, Ey, Ez, Bx, By, Bz)
            switch component 
                case 'X'
                    displayedE = Ex;
                    displayedB = Bx;
                    titleE = 'E-field vector x component (V/m)';
                    titleB = 'B-field vector x component (T)';
                case 'Y'
                    displayedE = Ey;
                    displayedB = By;
                    titleE = 'E-field vector y component (V/m)';
                    titleB = 'B-field vector y component (T)';
                case 'Z'
                    displayedE = Ez;
                    displayedB = Bz;
                    titleE = 'E-field vector z component (V/m)';
                    titleB = 'B-field vector z component (T)';
                otherwise
                    displayedE = app.Emag;
                    displayedB = app.Bmag;
                    titleE = 'E-field magnitude (V/m)';
                    titleB = 'B-field magnitude (T)';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear all; %#ok<CLALL>
            s = pwd; addpath([s, '\Engine']);
            s = pwd; addpath([s, '\temp']);
            s = pwd; addpath([s, '\GUI']);
            s = pwd; addpath([s, '\CoilPresets']);
            s = pwd; addpath([s, '\CoilPresets\Alternative_Mesh_Engine']);
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            app.arr_flag = 0;
            app.BusyLamp.Color = 'red';
            app.PreviewButton.Enable = 'off';
            drawnow();
            % Get data from fields
            value = app.CoiltypeDropDown.Value;
            switch value
                case 'Ring'
                    radius = app.RadiusmEditField.Value;
                    shape = app.ShapeDropDown.Value;
                    a = app.amEditField.Value;
                    b = app.bmEditField.Value; 
                    app.strcoil = coil_ring('radius', ...
                        radius,'crosssection',shape,'a', a,'b', b);
                case 'Spiral'
                    a0 = app.a0mEditField.Value;
                    b0 = app.b0mEditField.Value;
                    N = app.TurnsEditField.Value;
                    shape = app.ShapeDropDown.Value;
                    a = app.amEditField.Value;
                    b = app.bmEditField.Value;
                    app.strcoil = coil_spiral('a0',a0,'b0',b0,'turns',N, ...
                        'crosssection',shape,'a',a,'b',b);
                case 'Helix'
                    radius = app.RadiusmEditField.Value;
                    pitch = app.PitchmEditField.Value;
                    N = app.TurnsEditField.Value;
                    shape = app.ShapeDropDown.Value;
                    a = app.amEditField.Value;
                    b = app.bmEditField.Value;    
                    app.strcoil = coil_helix('radius',radius,'pitch',pitch, ...
                        'turns',N,'crosssection',shape,'a',a,'b',b);
                case 'CB60'
                    app.strcoil = coil_MagVenture_C_B60();
                case 'Cool40_Rat'
                    app.strcoil = coil_MagVenture_Cool40_Rat();
                case 'DB80'
                    app.strcoil = coil_MagVenture_D_B80();
                case 'MRiB91'
                    app.strcoil = coil_MagVenture_MRiB91();
                case 'TMSMEG'
                    app.strcoil = coil_MagVenture_TMSMEG();
                case 'B35'
                    app.strcoil = coil_MagVenture_B_35();
                case 'H1'
                    app.strcoil = coil_H1();
                case 'Three_Axes'
                    Xweight = app.XEditField.Value;
                    Yweight = app.YEditField.Value;
                    Zweight = app.ZEditField.Value;
                    [app.strcoil, app.ind_group, app.arr_flag] = coil_ThreeAxes(Xweight, Yweight, Zweight);
                case 'Three_Axes_X'
                    app.strcoil = coil_ThreeAxes_X();
                case 'Three_Axes_Y'
                    app.strcoil = coil_ThreeAxes_Y();
                case 'Three_Axes_Z'
                    app.strcoil = coil_ThreeAxes_Z();
                case 'Custom'
                    shape = app.ShapeDropDown.Value;
                    a = app.amEditField.Value;
                    b = app.bmEditField.Value;
                    if isempty(app.PcenterPath)
                        app.strcoil = coil_custom('a',a,'b',b,'crosssection',shape);
                    else
                        app.strcoil = coil_custom('a',a,'b',b,'crosssection',shape,'Centerline',app.PcenterPath);
                    end
            end
            
            % Preview coil
            %cla(app.UIAxes2);
            cla(app.UIAxes);
            display_CADmodel_GUI(app.UIAxes, app.strcoil.P, app.strcoil.t, 0, 11, 31);
            %display_wiremodel_GUI(app.UIAxes2, app.strcoil);
            app.BusyLamp.Color = 'green';
            app.PreviewButton.Enable = 'on';
            drawnow();
        end

        % Button pushed function: ComputefieldsButton
        function ComputefieldsButtonPushed(app, event)
           
            app.BusyLamp.Color = 'red';
            drawnow();
            fig = uifigure;
            d = uiprogressdlg(fig,'Title','Please Wait',...
            'Message','Initiating');

            % Get data from fields
            mu0 = 1.25663706e-6;  %   Magnetic permeability of vacuum(~air)
            plottype = app.PlottypeDropDown.Value;
            graphics = app.CoiloverlaySwitch.Value;
            vector = app.VectorSwitch.Value;
            threshold = app.Closethresholdm0fornotremovingsingularitiesEditField.Value;
            component = app.FieldComponentDropDown.Value;
            app.points = [];
            app.Emag = [];
            app.Bmag = [];
            xgrids = app.XgridsEditField.Value;
            ygrids = app.YgridsEditField.Value;
            zgrids = app.ZgridsEditField.Value;
            axis = app.SlicealongDropDown.Value;
            tic
            % Form grid
            d.Value = .45; 
            d.Message = 'Creating grids';
            app.x = linspace(app.XoffsetEditField.Value-app.xsizemEditField.Value/2, ...
                app.XoffsetEditField.Value+app.xsizemEditField.Value/2, ...
                xgrids);
            app.y = linspace(app.YoffsetEditField.Value-app.ysizemEditField.Value/2, ...
                app.YoffsetEditField.Value+app.ysizemEditField.Value/2, ...
                ygrids);
            app.z = linspace(app.ZoffsetEditField.Value-app.zsizemEditField.Value/2, ...
                app.ZoffsetEditField.Value+app.zsizemEditField.Value/2, ...
                zgrids);
            [X,Y,Z] = meshgrid(app.x,app.y,app.z);

            app.points(:, 1) = reshape(X, [], 1);
            app.points(:, 2) = reshape(Y, [], 1);
            app.points(:, 3) = reshape(Z, [], 1);

            % Compute fields
            d.Value = .5; 
            d.Message = 'Computing fields';

            %E = fmm_electric_field(app.strcoil, app.points, dIdt, mu0);
            E = fmm_electric_field_multi(app.coilgroup, app.points, mu0, ygrids, xgrids, zgrids);

            Ex = E(:,1);
            Ey = E(:,2);
            Ez = E(:,3);
            % Reshape matrices for quiver3
            app.Evx =  reshape(Ex, ygrids, xgrids, zgrids);
            app.Evy =  reshape(Ey, ygrids, xgrids, zgrids);
            app.Evz =  reshape(Ez, ygrids, xgrids, zgrids);
            B = fmm_magnetic_field_multi(app.coilgroup, app.points, mu0);
            Bx = B(:,1);
            By = B(:,2);
            Bz = B(:,3);
            app.Bvx =  reshape(Bx, ygrids, xgrids, zgrids);
            app.Bvy =  reshape(By, ygrids, xgrids, zgrids);
            app.Bvz =  reshape(Bz, ygrids, xgrids, zgrids);

            % Remove singularity
            if threshold ~= 0
                d.Value = .67; 
                d.Message = 'Removing singularities';
                [~, B] = singularity(app.strcoil, app.points, B, threshold);
                [~, E] = singularity(app.strcoil, app.points, E, threshold);
            end

            % Calculate magnitude
            d.Value = .9; 
            d.Message = 'Calculating magnitudes';
            app.Emag    = sqrt(dot(E, E, 2));
            app.Bmag    = sqrt(dot(B, B, 2));
            app.Emag = reshape(app.Emag, ygrids, xgrids, zgrids);
            app.Bmag = reshape(app.Bmag, ygrids, xgrids, zgrids);

            % Display and overlay coil graphics
            switch plottype
                % Decide sliders, which field is displayed, and title
                case 'Filled (2D)'
                    app.UIAxes3_2.Visible = 'on';
                    app.UIAxes4_2.Visible = 'on';
                    app.UIAxes3.Position = app.ETab.Position - [0, 0, app.UIAxes3_2.Position(3)+50 0];
                    app.UIAxes4.Position = app.BTab.Position - [0, 0, app.UIAxes4_2.Position(3)+50 0];
                    switch axis
                        case 'X'
                            app.LayerSlider.Limits = [1, length(app.x)];
                            app.LayerSlider.MajorTicks = 1:1:length(app.x);
                            app.CurrentlayerSpinner.Limits = [app.x(1), app.x(end)];
                            app.CurrentlayerSpinner.Step = app.x(2)- app.x(1);
                            app.CurrentlayerSpinner.Value =  app.x(1);
                            [app.displayedE, app.displayedB,...
                                app.titleE, app.titleB] = contour_display_helper(app, axis, component);
                        case 'Y'
                            app.LayerSlider.Limits = [1, length(app.y)];
                            app.LayerSlider.MajorTicks = 1:1:length(app.y);
                            app.CurrentlayerSpinner.Limits = [app.y(1), app.y(end)];
                            app.CurrentlayerSpinner.Step = app.y(2)- app.y(1);
                            app.CurrentlayerSpinner.Value =  app.y(1);
                            [app.displayedE, app.displayedB,...
                                app.titleE, app.titleB] = contour_display_helper(app, axis, component);
                        case 'Z'
                            app.LayerSlider.Limits = [1, length(app.z)];
                            app.LayerSlider.MajorTicks = 1:1:length(app.z);
                            app.CurrentlayerSpinner.Limits = [app.z(1), app.z(end)];
                            app.CurrentlayerSpinner.Step = app.z(2)- app.z(1);
                            app.CurrentlayerSpinner.Value =  app.z(1);
                            [app.displayedE, app.displayedB,...
                                app.titleE, app.titleB] = contour_display_helper(app, axis, component);
                    end
                    % Turn off minot ticks and set slider to 1
                    app.LayerSlider.MinorTicks = [];
                    app.LayerSlider.Value = 1;
                    % Plot layer 1
                    cla(app.UIAxes3, 'reset');
                    cla(app.UIAxes4, 'reset');
                    cla(app.UIAxes3_2, 'reset');
                    cla(app.UIAxes4_2, 'reset');
                    for i = 1:app.LayerSlider.Limits(2)   
                        display_contourf_Array(app.UIAxes3, app.x, app.y, app.z, app.displayedE, app.titleE,axis, i, app.coilgroup);
                        display_contourf_Array(app.UIAxes4, app.x, app.y, app.z, app.displayedB, app.titleB,axis, i, app.coilgroup);
                        display_ROI(app.UIAxes3_2, app.x,app.y,app.z);
                        display_ROI(app.UIAxes4_2, app.x,app.y,app.z);
                        Title = display_plane(app.UIAxes3_2, app.x,app.y,app.z, axis, i);
                        Title = display_plane(app.UIAxes4_2, app.x,app.y,app.z, axis, i);
                        display_CADmodel_Array(app.UIAxes3_2, app.coilgroup, 0, 11, 31, 0.3);
                        display_CADmodel_Array(app.UIAxes4_2, app.coilgroup, 0, 11, 31, 0.3);
                    end


                case '3D'
                    cla(app.UIAxes3, 'reset');
                    cla(app.UIAxes4, 'reset');
                    cla(app.UIAxes3_2, 'reset');
                    cla(app.UIAxes4_2, 'reset');
                    app.UIAxes3_2.Visible = 'off';
                    app.UIAxes4_2.Visible = 'off';
                    app.UIAxes3.Position = app.ETab.Position - [0 0 200 0];
                    app.UIAxes4.Position = app.BTab.Position - [0 0 200 0];
                    switch graphics
                        case 'On'
                            display_CADmodel_Array(app.UIAxes3, app.coilgroup, 0, 11, 31, 0.3);
                            display_CADmodel_Array(app.UIAxes4, app.coilgroup, 0, 11, 31, 0.3);
                    end
                    display_3D_contour(app.UIAxes3,X,Y,Z,app.Emag,'Emag, V/m', app.x, app.y, app.z, axis);
                    display_3D_contour(app.UIAxes4,X,Y,Z,app.Bmag,'Bmag, T', app.x, app.y, app.z, axis);
                    hold(app.UIAxes3, 'on');
                    hold(app.UIAxes4, 'on');
                    switch vector
                        case 'On'
                            quiver3(app.UIAxes3,X,Y,Z,app.Evx,app.Evy,app.Evz, 'Color','magenta');
                            quiver3(app.UIAxes4,X,Y,Z,app.Bvx,app.Bvy,app.Bvz, 'Color','magenta');
                    end
                case 'Conductive Sphere'
                    cla(app.UIAxes3_2, 'reset');
                    cla(app.UIAxes4_2, 'reset');
                    app.UIAxes3_2.Visible = 'off';
                    app.UIAxes4_2.Visible = 'off';
%                     app.UIAxes3.Position = app.ETab.Position - [0 0 200 0];
%                     app.UIAxes4.Position = app.BTab.Position - [0 0 200 0];
                    d.Message = 'Calculating tangential E field just inside/outside conductive sphere';
                    app.points  = meshtricenter(app.S.P, app.S.t);
                    E = fmm_electric_field_multi_sphere(app.coilgroup, app.points, mu0);
                    E = solver(app.S, E); 
                    % Get components
                    Ex = E(:,1);
                    Ey = E(:,2);
                    Ez = E(:,3);
                    app.Emag = sqrt(dot(E, E, 2));
                    d.Message = 'Calculating B field';
                    B = fmm_magnetic_field_multi(app.coilgroup, app.points, mu0);
                    Bx = B(:,1);
                    By = B(:,2);
                    Bz = B(:,3);
                    app.Bmag = sqrt(dot(B, B, 2));
                    [app.displayedE, app.displayedB, app.titleE, app.titleB] = Sphere_display_helper(app, component, Ex, Ey, Ez, Bx, By, Bz);
                    cla(app.UIAxes3,'reset');
                    cla(app.UIAxes4, 'reset');
                    display_oversurface_GUI(app.UIAxes3, app.S, app.displayedE, app.titleE);
                    hold(app.UIAxes3, 'on');
                    display_CADmodel_Array(app.UIAxes3, app.coilgroup, 0, 11, 31, 0.3);
                    display_oversurface_GUI(app.UIAxes4, app.S, app.displayedB, app.titleB);
                    hold(app.UIAxes3, 'on');
                    display_CADmodel_Array(app.UIAxes4, app.coilgroup, 0, 11, 31, 0.3);

            end
            close(d)
            close(fig)
            app.BusyLamp.Color = 'green';
            drawnow();
            app.Location = uigetdir(pwd,'Select save folder'); 
            if ~exist(fullfile(app.Location, 'Output'), 'dir')
                mkdir(fullfile(app.Location, 'Output'));
            end
            p = app.points;
            save(fullfile(app.Location, 'Output', 'points'), 'p');
            E = app.Emag;
            save(fullfile(app.Location, 'Output', 'Emag'), 'E');
            B = app.Bmag;
            save(fullfile(app.Location, 'Output', 'Bmag'), 'B');
            coils = app.coilgroup;
            save(fullfile(app.Location, 'Output',  'strcoils'), 'coils');
            evx = app.Evx;
            save(fullfile(app.Location, 'Output', 'E-field-Vector-X-Components'), 'evx');
            evy = app.Evy;
            save(fullfile(app.Location, 'Output', 'E-field-Vector-Y-Components'), 'evy');
            evz = app.Evz;
            save(fullfile(app.Location, 'Output', 'E-field-Vector-Z-Components'), 'evz');
            bvx = app.Bvx;
            save(fullfile(app.Location, 'Output', 'B-field-Vector-X-Components'), 'bvx');
            bvy = app.Bvy;
            save(fullfile(app.Location, 'Output', 'B-field-Vector-Y-Components'), 'bvy');
            bvz = app.Bvz;
            save(fullfile(app.Location, 'Output', 'B-field-Vector-Z-Components'), 'bvz');
            indmat = app.indMat;
            save(fullfile(app.Location, 'Output', 'inductanc-ematrix'), 'indmat');
        end

        % Button pushed function: AddcoiltocanvasButton
        function AddcoiltocanvasButtonPushed(app, event)
            app.BusyLamp.Color = 'red';
            drawnow();
            if strcmp(app.CoilIDEditField.Value, '')
                fig = uifigure;
                uialert(fig,"Label cannot be empty.","Please provide a label.");
            else
            selected = app.SelectCoilDropDown.Value;
            % Get displacements
            MoveX = app.xmEditField.Value;
            MoveY = app.ymEditField.Value;
            MoveZ = app.zmEditField.Value;
            % Get rotations
            tx = deg2rad(app.xrotationdegEditField.Value);
            ty = deg2rad(app.yrotationdegEditField.Value);
            tz = deg2rad(app.zrotationdegEditField.Value);

            % Get simulation parameters
            dIdt = app.dIdTAsecEditField.Value;
            I0 =  app.CurrentAEditField.Value;
            switch selected
                case 'New Coil'                 
                    % Move coil and add to struct
                    strcoiltemp = positioncoil_GUI(app.strcoil, tx, ty, tz, MoveX, MoveY, MoveZ);
                    strcoiltemp.label = app.CoilIDEditField.Value;
                    strcoiltemp.dIdt = dIdt;
                    strcoiltemp.I0 = I0;
                    app.coilgroup = [app.coilgroup; strcoiltemp];
                    positionstemp.label = app.CoilIDEditField.Value;
                    positionstemp.MoveX = MoveX;
                    positionstemp.MoveY = MoveY;
                    positionstemp.MoveZ = MoveZ;
                    positionstemp.tx = tx;
                    positionstemp.ty = ty;
                    positionstemp.tz = tz;
                    app.arrayPositions = [app.arrayPositions; positionstemp];
                otherwise

            end

            % Graphics
            cla(app.UIAxes2_2);
            display_CADmodel_Array(app.UIAxes2_2, app.coilgroup, 0, 11, 31, 1);

            % Update drop-downs
            app.SelectCoilDropDown.Items = [app.SelectCoilDropDown.Items strcoiltemp.label];
            app.SelectCoilDropDown.Value = 'New Coil';
            app.CoilIDEditField.Value = string(length(app.coilgroup)+1);
            app.BusyLamp.Color = 'green';
            drawnow();
            end
        end

        % Value changed function: CoiltypeDropDown
        function CoiltypeDropDownValueChanged(app, event)
            value = app.CoiltypeDropDown.Value;
            switch value
                case 'Ring'
                    app.CenterlineLabel.Visible = 'on';
                    app.RadiusmEditField.Visible = "on";
                    app.RadiusmEditFieldLabel.Visible = "on";
                    app.PitchmEditField.Visible = "off";
                    app.PitchmLabel.Visible = "off";
                    app.TurnsEditField.Visible = "off";
                    app.TurnsLabel.Visible = "off";
                    app.a0mEditFieldLabel.Visible = "off";
                    app.a0mEditField.Visible = "off";
                    app.b0mEditField.Visible = "off";
                    app.b0mEditFieldLabel.Visible = "off";
                    app.FromSTLLabel.Visible = "off";
                    app.LaunchWizardButton.Visible = "off";
                    app.FrommatLabel.Visible = "off";
                    app.LoadButton.Visible = "off";
                    app.CrosssectionLabel.Visible = 'on';
                    app.ShapeDropDown.Visible = 'on';
                    app.ShapeDropDownLabel.Visible = 'on';
                    app.amEditField.Visible = 'on';
                    app.amEditFieldLabel.Visible = 'on';
                    app.bmEditField.Visible = 'on';
                    app.bmLabel.Visible = 'on';
                    app.ArrayweightsLabel.Visible = 'off';
                    app.XEditField.Visible = 'off';
                    app.XEditFieldLabel.Visible = 'off';
                    app.YEditField.Visible = 'off';
                    app.YEditFieldLabel.Visible = 'off';
                    app.ZEditField.Visible = 'off';
                    app.ZEditFieldLabel.Visible = 'off';
                case 'Spiral'
                    app.CenterlineLabel.Visible = 'on';
                    app.RadiusmEditField.Visible = "off";
                    app.RadiusmEditFieldLabel.Visible = "off";
                    app.PitchmEditField.Visible = "off";
                    app.PitchmLabel.Visible = "off";
                    app.TurnsEditField.Visible = "on";
                    app.TurnsLabel.Visible = "on";
                    app.a0mEditFieldLabel.Visible = "on";
                    app.a0mEditField.Visible = "on";
                    app.b0mEditField.Visible = "on";
                    app.b0mEditFieldLabel.Visible = "on";
                    app.FromSTLLabel.Visible = "off";
                    app.LaunchWizardButton.Visible = "off";
                    app.FrommatLabel.Visible = "off";
                    app.LoadButton.Visible = "off";
                    app.CrosssectionLabel.Visible = 'on';
                    app.ShapeDropDown.Visible = 'on';
                    app.ShapeDropDownLabel.Visible = 'on';
                    app.amEditField.Visible = 'on';
                    app.amEditFieldLabel.Visible = 'on';
                    app.bmEditField.Visible = 'on';
                    app.bmLabel.Visible = 'on';
                    app.ArrayweightsLabel.Visible = 'off';
                    app.XEditField.Visible = 'off';
                    app.XEditFieldLabel.Visible = 'off';
                    app.YEditField.Visible = 'off';
                    app.YEditFieldLabel.Visible = 'off';
                    app.ZEditField.Visible = 'off';
                    app.ZEditFieldLabel.Visible = 'off';
                case 'Helix'
                    app.CenterlineLabel.Visible = 'on';
                    app.RadiusmEditField.Visible = "on";
                    app.RadiusmEditFieldLabel.Visible = "on";
                    app.PitchmEditField.Visible = "on";
                    app.PitchmLabel.Visible = "on";
                    app.TurnsEditField.Visible = "on";
                    app.TurnsLabel.Visible = "on";
                    app.a0mEditFieldLabel.Visible = "off";
                    app.a0mEditField.Visible = "off";
                    app.b0mEditField.Visible = "off";
                    app.b0mEditFieldLabel.Visible = "off";
                    app.FromSTLLabel.Visible = "off";
                    app.LaunchWizardButton.Visible = "off";
                    app.FrommatLabel.Visible = "off";
                    app.LoadButton.Visible = "off";
                    app.CrosssectionLabel.Visible = 'on';
                    app.ShapeDropDown.Visible = 'on';
                    app.ShapeDropDownLabel.Visible = 'on';
                    app.amEditField.Visible = 'on';
                    app.amEditFieldLabel.Visible = 'on';
                    app.bmEditField.Visible = 'on';
                    app.bmLabel.Visible = 'on';
                    app.ArrayweightsLabel.Visible = 'off';
                    app.XEditField.Visible = 'off';
                    app.XEditFieldLabel.Visible = 'off';
                    app.YEditField.Visible = 'off';
                    app.YEditFieldLabel.Visible = 'off';
                    app.ZEditField.Visible = 'off';
                    app.ZEditFieldLabel.Visible = 'off';
                case 'Custom'
                    app.CenterlineLabel.Visible = 'on';
                    app.RadiusmEditField.Visible = "off";
                    app.RadiusmEditFieldLabel.Visible = "off";
                    app.PitchmEditField.Visible = "off";
                    app.PitchmLabel.Visible = "off";
                    app.TurnsEditField.Visible = "off";
                    app.TurnsLabel.Visible = "off";
                    app.a0mEditFieldLabel.Visible = "off";
                    app.a0mEditField.Visible = "off";
                    app.b0mEditField.Visible = "off";
                    app.b0mEditFieldLabel.Visible = "off";
                    app.FromSTLLabel.Visible = "on";
                    app.LaunchWizardButton.Visible = "on";
                    app.FrommatLabel.Visible = "on";
                    app.LoadButton.Visible = "on";
                    app.CrosssectionLabel.Visible = 'on';
                    app.ShapeDropDown.Visible = 'on';
                    app.ShapeDropDownLabel.Visible = 'on';
                    app.amEditField.Visible = 'on';
                    app.amEditFieldLabel.Visible = 'on';
                    app.bmEditField.Visible = 'on';
                    app.bmLabel.Visible = 'on';
                    app.ArrayweightsLabel.Visible = 'off';
                    app.XEditField.Visible = 'off';
                    app.XEditFieldLabel.Visible = 'off';
                    app.YEditField.Visible = 'off';
                    app.YEditFieldLabel.Visible = 'off';
                    app.ZEditField.Visible = 'off';
                    app.ZEditFieldLabel.Visible = 'off';
                case 'Three_Axes'
                    app.CenterlineLabel.Visible = 'off';
                    app.RadiusmEditField.Visible = "off";
                    app.RadiusmEditFieldLabel.Visible = "off";
                    app.PitchmEditField.Visible = "off";
                    app.PitchmLabel.Visible = "off";
                    app.TurnsEditField.Visible = "off";
                    app.TurnsLabel.Visible = "off";
                    app.a0mEditFieldLabel.Visible = "off";
                    app.a0mEditField.Visible = "off";
                    app.b0mEditField.Visible = "off";
                    app.b0mEditFieldLabel.Visible = "off";
                    app.FromSTLLabel.Visible = "off";
                    app.LaunchWizardButton.Visible = "off";
                    app.FrommatLabel.Visible = "off";
                    app.LoadButton.Visible = "off";
                    app.CrosssectionLabel.Visible = 'off';
                    app.ShapeDropDown.Visible = 'off';
                    app.ShapeDropDownLabel.Visible = 'off';
                    app.amEditField.Visible = 'off';
                    app.amEditFieldLabel.Visible = 'off';
                    app.bmEditField.Visible = 'off';
                    app.bmLabel.Visible = 'off';
                    app.ArrayweightsLabel.Visible = 'on';
                    app.XEditField.Visible = 'on';
                    app.XEditFieldLabel.Visible = 'on';
                    app.YEditField.Visible = 'on';
                    app.YEditFieldLabel.Visible = 'on';
                    app.ZEditField.Visible = 'on';
                    app.ZEditFieldLabel.Visible = 'on';
                otherwise
                    app.CenterlineLabel.Visible = 'off';
                    app.RadiusmEditField.Visible = "off";
                    app.RadiusmEditFieldLabel.Visible = "off";
                    app.PitchmEditField.Visible = "off";
                    app.PitchmLabel.Visible = "off";
                    app.TurnsEditField.Visible = "off";
                    app.TurnsLabel.Visible = "off";
                    app.a0mEditFieldLabel.Visible = "off";
                    app.a0mEditField.Visible = "off";
                    app.b0mEditField.Visible = "off";
                    app.b0mEditFieldLabel.Visible = "off";
                    app.FromSTLLabel.Visible = "off";
                    app.LaunchWizardButton.Visible = "off";
                    app.FrommatLabel.Visible = "off";
                    app.LoadButton.Visible = "off";
                    app.CrosssectionLabel.Visible = 'off';
                    app.ShapeDropDown.Visible = 'off';
                    app.ShapeDropDownLabel.Visible = 'off';
                    app.amEditField.Visible = 'off';
                    app.amEditFieldLabel.Visible = 'off';
                    app.bmEditField.Visible = 'off';
                    app.bmLabel.Visible = 'off';
                    app.ArrayweightsLabel.Visible = 'off';
                    app.XEditField.Visible = 'off';
                    app.XEditFieldLabel.Visible = 'off';
                    app.YEditField.Visible = 'off';
                    app.YEditFieldLabel.Visible = 'off';
                    app.ZEditField.Visible = 'off';
                    app.ZEditFieldLabel.Visible = 'off';                  
            end
        end

        % Button pushed function: LaunchWizardButton
        function LaunchWizardButtonPushed(app, event)
            centerline_extractor;
            app.PcenterPath = [];
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,location] = uigetfile('*.mat');
            app.PcenterPath = fullfile(location,file);
        end

        % Button pushed function: ClearallcoilsButton
        function ClearallcoilsButtonPushed(app, event)
            app.BusyLamp.Color = 'red';
            drawnow();
            cla(app.UIAxes2_2);
            app.coilgroup = [];
            app.SelectCoilDropDown.Items(:) = [];
            app.SelectCoilDropDown.Items = {'New Coil'};
            app.BusyLamp.Color = 'green';
            drawnow();
        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
            % Sync tabs
            selectedTab = app.TabGroup2.SelectedTab;
            switch selectedTab
                case app.CoilParameterTab
                    app.TabGroup.SelectedTab = app.PreviewTab;
                case app.PlacementTab
                    app.TabGroup.SelectedTab = app.CanvasTab;
                case app.SimulationTab
                    app.TabGroup.SelectedTab = app.ETab;
            end
        end

        % Value changed function: LayerSlider
        function LayerSliderValueChanged(app, event)
            app.BusyLamp.Color = 'red';
            drawnow();
            layer = round(app.LayerSlider.Value);
            axis = app.SlicealongDropDown.Value;

            % Update plot based on slider
            Title = display_plane(app.UIAxes3_2, app.x,app.y,app.z, axis, layer);
            Title = display_plane(app.UIAxes4_2, app.x,app.y,app.z, axis, layer);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            display_contourf_Array(app.UIAxes3, app.x, app.y, app.z, app.displayedE, app.titleE,axis, layer, app.coilgroup);
            display_contourf_Array(app.UIAxes4, app.x, app.y, app.z, app.displayedB, app.titleB,axis, layer, app.coilgroup);
            switch axis
                case 'X'
                    app.CurrentlayerSpinner.Value = app.x(layer);
                case 'Y'
                    app.CurrentlayerSpinner.Value = app.y(layer);
                case 'Z'
                    app.CurrentlayerSpinner.Value = app.z(layer);
            end
            app.BusyLamp.Color = 'green';
            drawnow();

        end

        % Value changed function: PlottypeDropDown
        function PlottypeValueChanged(app, event)
            type = app.PlottypeDropDown.Value;
            switch type
                case '3D'
                    app.CoiloverlaySwitch.Visible = 'on';
                    app.CoiloverlaySwitchLabel.Visible = 'on';
                    app.VectorSwitch.Visible = 'on';
                    app.VectorLabel.Visible = 'on';
                    app.LayerSlider.Visible = 'off';
                    app.LayerSliderLabel.Visible = 'off';
                    app.CurrentlayerSpinner.Visible = 'off';
                    app.CurrentlayerSpinnerLabel.Visible = 'off';
                    app.xsizemEditField.Enable = 'on';
                    app.ysizemEditField.Enable = 'on';
                    app.zsizemEditField.Enable = 'on';
                    app.XoffsetEditField.Enable = 'on';
                    app.YoffsetEditField.Enable = 'on';
                    app.ZoffsetEditField.Enable = 'on';
                    app.XgridsEditField.Enable = 'on';
                    app.YgridsEditField.Enable = 'on';
                    app.ZgridsEditField.Enable = 'on';
                    app.SlicealongDropDown.Enable = 'on';
                    app.SlicealongDropDownLabel.Enable = 'on';
                    app.FieldComponentDropDown.Enable = 'off';
                    app.Closethresholdm0fornotremovingsingularitiesEditField.Enable = 'on';
                case 'Filled (2D)'
                    app.CoiloverlaySwitch.Visible = 'off';
                    app.CoiloverlaySwitchLabel.Visible = 'off';
                    app.VectorSwitch.Visible = 'off';
                    app.VectorLabel.Visible = 'off';
                    app.LayerSlider.Visible = 'on';
                    app.LayerSliderLabel.Visible = 'on';
                    app.CurrentlayerSpinner.Visible = 'on';
                    app.CurrentlayerSpinnerLabel.Visible = 'on';
                    app.xsizemEditField.Enable = 'on';
                    app.ysizemEditField.Enable = 'on';
                    app.zsizemEditField.Enable = 'on';
                    app.XoffsetEditField.Enable = 'on';
                    app.YoffsetEditField.Enable = 'on';
                    app.ZoffsetEditField.Enable = 'on';
                    app.XgridsEditField.Enable = 'on';
                    app.YgridsEditField.Enable = 'on';
                    app.ZgridsEditField.Enable = 'on';
                    app.SlicealongDropDown.Enable = 'on';
                    app.SlicealongDropDownLabel.Enable = 'on';
                    app.FieldComponentDropDown.Enable = 'on';
                    app.FieldComponentDropDownLabel.Enable = 'on';
                    app.Closethresholdm0fornotremovingsingularitiesEditField.Enable = 'on';
                case 'Conductive Sphere'
                    app.CoiloverlaySwitch.Visible = 'off';
                    app.CoiloverlaySwitchLabel.Visible = 'off';
                    app.VectorSwitch.Visible = 'off';
                    app.VectorLabel.Visible = 'off';
                    app.LayerSlider.Visible = 'off';
                    app.LayerSliderLabel.Visible = 'off';
                    app.CurrentlayerSpinner.Visible = 'off';
                    app.CurrentlayerSpinnerLabel.Visible = 'off';
                    app.xsizemEditField.Enable = 'off';
                    app.ysizemEditField.Enable = 'off';
                    app.zsizemEditField.Enable = 'off';
                    app.XoffsetEditField.Enable = 'off';
                    app.YoffsetEditField.Enable = 'off';
                    app.ZoffsetEditField.Enable = 'off';
                    app.XgridsEditField.Enable = 'off';
                    app.YgridsEditField.Enable = 'off';
                    app.ZgridsEditField.Enable = 'off';
                    app.SlicealongDropDown.Enable = 'off';
                    app.SlicealongDropDownLabel.Enable = 'off';
                    app.FieldComponentDropDown.Enable = 'on';
                    app.Closethresholdm0fornotremovingsingularitiesEditField.Enable = 'off';

            end
        end

        % Button pushed function: ComputeInductanceButton
        function ComputeInductanceButtonPushed(app, event)
            app.BusyLamp.Color = 'red';
            drawnow();
            % Get values
            mu0 = 1.25663706e-006;

            if app.arr_flag == 0
                app.ind_group = app.coilgroup;
            end
            coils = app.ind_group;
            labels = {};
            for n = 1:length(coils)
                labels{end+1} = coils(n).label;
            end
                
           app.UITable.ColumnName = labels;
           app.UITable.RowName = labels;
           app.indMat = fmm_inductance_matrix(app.ind_group,mu0);
           set(app.UITable, 'Data', app.indMat);

           app.BusyLamp.Color = 'green';
           drawnow();

           % OLD IMPLEMENTATION

           % coil1_label = app.Coil1DropDown.Value;
           % coil2_label = app.Coil2DropDown.Value;

           %  % Find coils
           %  for coil1_idx = 1:length(coils)
           %      entry = coils(coil1_idx).label;
           %      if strcmp(entry, coil1_label)
           %          coil1 = app.coilgroup(coil1_idx);
           %          break
           %      end
           %  end
           % for coil2_idx = 1:length(coils)
           %      entry = coils(coil2_idx).label;
           %      if strcmp(entry, coil2_label)
           %          coil2 = app.coilgroup(coil2_idx);
           %          break
           %      end
           % end
           % 
           % % Compute inductances
           % app.Coil1selfinductanceHEditField.Value = fmm_self_inductance(coil1, mu0);
           % app.Coil2selfinductanceHEditField.Value = fmm_self_inductance(coil2, mu0);
           % app.MutualInductanceHEditField.Value = fmm_mutual_inductance(coil1, coil2, mu0);

        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)

            delete_helper(app);
        end

        % Callback function
        function PushToolClicked(app, event)
            app.Location = uigetdir; 
            if ~exist(fullfile(app.Location, 'Output'), 'dir')
                mkdir(fullfile(app.Location, 'Output'));
            end
            p = app.points;
            save(fullfile(app.Location, 'Output', 'points'), 'p');
            E = app.Emag;
            save(fullfile(app.Location, 'Output', 'Emag'), 'E');
            B = app.Bmag;
            save(fullfile(app.Location, 'Output', 'Bmag'), 'B');
            coils = app.coilgroup;
            save(fullfile(app.Location, 'Output',  'strcoils'), 'coils');
            evx = app.Evx;
            save(fullfile(app.Location, 'Output', 'E-field-Vector-X-Components'), 'evx');
            evy = app.Evy;
            save(fullfile(app.Location, 'Output', 'E-field-Vector-Y-Components'), 'evy');
            evz = app.Evz;
            save(fullfile(app.Location, 'Output', 'E-field-Vector-Z-Components'), 'evz');
            bvx = app.Bvx;
            save(fullfile(app.Location, 'Output', 'B-field-Vector-X-Components'), 'bvx');
            bvy = app.Bvy;
            save(fullfile(app.Location, 'Output', 'B-field-Vector-Y-Components'), 'bvy');
            bvz = app.Bvz;
            save(fullfile(app.Location, 'Output', 'B-field-Vector-Z-Components'), 'bvz');
            indmat = app.indMat;
            save(fullfile(app.Location, 'Output', 'inductanc-ematrix'), 'indmat');
        end

        % Callback function
        function PushTool3Clicked(app, event)
            url = fullfile(pwd, 'documentation', 'help.html');
            web(url)
        end

        % Value changed function: SelectCoilDropDown
        function SelectCoilDropDownValueChanged(app, event)
            coil_label = app.SelectCoilDropDown.Value;
            coil_positions = app.arrayPositions;
            coils = app.coilgroup;
            switch coil_label
                case 'New Coil'
                    app.AddcoiltocanvasButton.Enable = 'on';
                    app.CoilIDEditField.Value = string(length(app.coilgroup)+1);
                    app.DeleteButton.Enable = 'off';
                otherwise
                    app.AddcoiltocanvasButton.Enable = 'off';
                    app.DeleteButton.Enable = 'on';
                    % Find coil
                    for coil_idx = 1:length(coils)
                        entry = coils(coil_idx).label;
                        if strcmp(entry, coil_label)
                            break
                        end
                    end
                    for pos_idx = 1:length(coil_positions)
                        entry = coil_positions(pos_idx).label;
                        if strcmp(entry, coil_label)
                            break
                        end
                    end
                    % Write displacements
                    app.xmEditField.Value = coil_positions(pos_idx).MoveX; 
                    app.ymEditField.Value = coil_positions(pos_idx).MoveY; 
                    app.zmEditField.Value = coil_positions(pos_idx).MoveZ;
                    % Write rotations
                    app.xrotationdegEditField.Value = rad2deg(coil_positions(pos_idx).tx); 
                    app.yrotationdegEditField.Value = rad2deg(coil_positions(pos_idx).ty); 
                    app.zrotationdegEditField.Value = rad2deg(coil_positions(pos_idx).tz); 
        
                    % Write simulation parameters
                    app.dIdTAsecEditField.Value = coils(coil_idx).dIdt;
                    app.CurrentAEditField.Value = coils(coil_idx).I0;
                    app.CoilIDEditField.Value = coil_label;
            end

        end

        % Value changed function: CurrentlayerSpinner
        function CurrentlayerSpinnerValueChanged(app, event)
            app.BusyLamp.Color = 'red';
            drawnow();
            value = app.CurrentlayerSpinner.Value;
            axis = app.SlicealongDropDown.Value;
            switch axis
                case 'X'
                    layer = round (1 + abs(value - app.x(1))/(app.x(2) - app.x(1)));
                    app.LayerSlider.Value = layer;
                case 'Y'
                    layer = round (1 + abs(value - app.y(1))/(app.y(2) - app.y(1)));
                    app.LayerSlider.Value = layer;
                case 'Z'
                    layer = round (1 + abs(value - app.z(1))/(app.z(2) - app.z(1)));
                    app.LayerSlider.Value = layer;
            end
            % Update plot based on spinner
            Title = display_plane(app.UIAxes3_2, app.x,app.y,app.z, axis, layer);
            Title = display_plane(app.UIAxes4_2, app.x,app.y,app.z, axis, layer);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            display_contourf_Array(app.UIAxes3, app.x, app.y, app.z, app.displayedE, app.titleE,axis, layer, app.coilgroup);
            display_contourf_Array(app.UIAxes4, app.x, app.y, app.z, app.displayedB, app.titleB,axis, layer, app.coilgroup);

            app.BusyLamp.Color = 'green';
            drawnow();
        end

        % Button pushed function: AddSpheretocanvasButton
        function AddSpheretocanvasButtonPushed(app, event)
            app.BusyLamp.Color = 'red';
            drawnow();
            dx = app.xmEditField_2.Value;
            dy = app.ymEditField_2.Value;
            dz = app.zmEditField_2.Value;
            r = app.RadiusmEditField_2.Value;
            app.S       = load('meshsphere.mat');
            app.S.P  = r*app.S.P; 
            app.S.P(:, 1) = app.S.P(:, 1) + dx;  
            app.S.P(:, 2) = app.S.P(:, 2) + dy; 
            app.S.P(:, 3) = app.S.P(:, 3) + dz;  
            display_CADmodel_GUI(app.UIAxes2_2, app.S.P, app.S.t, 0, 11, 31);
            app.BusyLamp.Color = 'green';
            drawnow();
        end

        % Button pushed function: DeleteSphereButton
        function DeleteSphereButtonPushed(app, event)
            app.BusyLamp.Color = 'red';
            drawnow();
            app.S = [];
            cla(app.UIAxes2_2);
            display_CADmodel_Array(app.UIAxes2_2, app.coilgroup, 0, 11, 31, 1);
            app.BusyLamp.Color = 'green';
            drawnow();
        end

        % Callback function
        function PushTool4Clicked(app, event)
            delete(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.CoilDesignerV09UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {791, 791};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {344, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CoilDesignerV09UIFigure = uifigure('Visible', 'off');
            app.CoilDesignerV09UIFigure.Name = 'Coil Designer V0.9';
            app.CoilDesignerV09UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.CoilDesignerV09UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.CoilParameterTab = uitab(app.TabGroup2);
            app.CoilParameterTab.Title = '1. Coil Parameter';
            app.GridLayout2 = uigridlayout(app.CoilParameterTab);
            app.CrosssectionLabel = uilabel(app.GridLayout2);
            app.CrosssectionLabel.Text = 'Cross-section:';
            app.PreviewButton = uibutton(app.GridLayout2, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.CoiltypeLabel = uilabel(app.GridLayout2);
            app.CoiltypeLabel.Text = 'Coil type:';
            app.CoiltypeDropDown = uidropdown(app.GridLayout2);
            app.CoiltypeDropDown.Items = {'Ring', 'Spiral', 'Helix', 'CB60', 'Cool40_Rat', 'DB80', 'MRiB91', 'TMSMEG', 'B35', 'H1', 'Three_Axes', 'Three_Axes_X', 'Three_Axes_Y', 'Three_Axes_Z', 'Custom'};
            app.CoiltypeDropDown.ValueChangedFcn = createCallbackFcn(app, @CoiltypeDropDownValueChanged, true);
            app.CoiltypeDropDown.Value = 'Helix';
            app.a0mEditFieldLabel = uilabel(app.GridLayout2);
            app.a0mEditFieldLabel.Visible = 'off';
            app.a0mEditFieldLabel.Text = 'a0(m):         ';
            app.a0mEditField = uieditfield(app.GridLayout2, 'numeric');
            app.a0mEditField.Visible = 'off';
            app.a0mEditField.Value = 0.03;
            app.RadiusmEditFieldLabel = uilabel(app.GridLayout2);
            app.RadiusmEditFieldLabel.Text = 'Radius (m):';
            app.RadiusmEditField = uieditfield(app.GridLayout2, 'numeric');
            app.RadiusmEditField.Value = 0.05;
            app.b0mEditFieldLabel = uilabel(app.GridLayout2);
            app.b0mEditFieldLabel.Visible = 'off';
            app.b0mEditFieldLabel.Text = 'b0 (m):         ';
            app.b0mEditField = uieditfield(app.GridLayout2, 'numeric');
            app.b0mEditField.Visible = 'off';
            app.b0mEditField.Value = 0.002;
            app.PitchmLabel = uilabel(app.GridLayout2);
            app.PitchmLabel.Text = 'Pitch (m):    ';
            app.PitchmEditField = uieditfield(app.GridLayout2, 'numeric');
            app.PitchmEditField.Value = 0.01;
            app.TurnsLabel = uilabel(app.GridLayout2);
            app.TurnsLabel.Text = 'Turns :         ';
            app.TurnsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TurnsEditField.Value = 5;
            app.ShapeDropDownLabel = uilabel(app.GridLayout2);
            app.ShapeDropDownLabel.Text = 'Shape:       ';
            app.ShapeDropDown = uidropdown(app.GridLayout2);
            app.ShapeDropDown.Items = {'Rectangle', 'Ellipse'};
            app.ShapeDropDown.Value = 'Rectangle';
            app.amEditFieldLabel = uilabel(app.GridLayout2);
            app.amEditFieldLabel.Text = 'a (m):           ';
            app.amEditField = uieditfield(app.GridLayout2, 'numeric');
            app.amEditField.Value = 0.005;
            app.bmLabel = uilabel(app.GridLayout2);
            app.bmLabel.Text = 'b (m):          ';
            app.bmEditField = uieditfield(app.GridLayout2, 'numeric');
            app.bmEditField.Value = 0.005;
            app.LaunchWizardButton = uibutton(app.GridLayout2, 'push');
            app.LaunchWizardButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchWizardButtonPushed, true);
            app.LaunchWizardButton.Visible = 'off';
            app.LaunchWizardButton.Text = 'Launch Wizard';
            app.CenterlineLabel = uilabel(app.GridLayout2);
            app.CenterlineLabel.Text = 'Centerline:';
            app.FromSTLLabel = uilabel(app.GridLayout2);
            app.FromSTLLabel.Visible = 'off';
            app.FromSTLLabel.Text = 'From STL:';
            app.FrommatLabel = uilabel(app.GridLayout2);
            app.FrommatLabel.Visible = 'off';
            app.FrommatLabel.Text = 'From .mat:';
            app.LoadButton = uibutton(app.GridLayout2, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Visible = 'off';
            app.LoadButton.Text = 'Load';
            app.ArrayweightsLabel = uilabel(app.GridLayout2);
            app.ArrayweightsLabel.Text = 'Array weights:';
            app.XEditFieldLabel = uilabel(app.GridLayout2);
            app.XEditFieldLabel.Text = 'X:';
            app.XEditField = uieditfield(app.GridLayout2, 'numeric');
            app.YEditFieldLabel = uilabel(app.GridLayout2);
            app.YEditFieldLabel.Text = 'Y:';
            app.YEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ZEditFieldLabel = uilabel(app.GridLayout2);
            app.ZEditFieldLabel.Text = 'Z:';
            app.ZEditField = uieditfield(app.GridLayout2, 'numeric');
            app.PlacementTab = uitab(app.TabGroup2);
            app.PlacementTab.Title = '2. Placement';
            app.GridLayout3 = uigridlayout(app.PlacementTab);
            app.AddcoiltocanvasButton = uibutton(app.GridLayout3, 'push');
            app.AddcoiltocanvasButton.ButtonPushedFcn = createCallbackFcn(app, @AddcoiltocanvasButtonPushed, true);
            app.AddcoiltocanvasButton.Text = 'Add coil to canvas';
            app.xmEditFieldLabel = uilabel(app.GridLayout3);
            app.xmEditFieldLabel.Text = 'x (m):';
            app.xmEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ymEditFieldLabel = uilabel(app.GridLayout3);
            app.ymEditFieldLabel.Text = 'y (m):';
            app.ymEditField = uieditfield(app.GridLayout3, 'numeric');
            app.zmEditFieldLabel = uilabel(app.GridLayout3);
            app.zmEditFieldLabel.Text = 'z (m):';
            app.zmEditField = uieditfield(app.GridLayout3, 'numeric');
            app.xrotationdegEditFieldLabel = uilabel(app.GridLayout3);
            app.xrotationdegEditFieldLabel.Text = 'x rotation (deg):';
            app.xrotationdegEditField = uieditfield(app.GridLayout3, 'numeric');
            app.yrotationdegEditFieldLabel = uilabel(app.GridLayout3);
            app.yrotationdegEditFieldLabel.Text = 'y rotation (deg):';
            app.yrotationdegEditField = uieditfield(app.GridLayout3, 'numeric');
            app.zrotationdegEditFieldLabel = uilabel(app.GridLayout3);
            app.zrotationdegEditFieldLabel.Text = 'z rotation (deg):';
            app.zrotationdegEditField = uieditfield(app.GridLayout3, 'numeric');
            app.CurrentAEditFieldLabel = uilabel(app.GridLayout3);
            app.CurrentAEditFieldLabel.Text = 'Current (A):';
            app.CurrentAEditField = uieditfield(app.GridLayout3, 'numeric');
            app.CurrentAEditField.Value = 2500;
            app.dIdTAsecEditFieldLabel = uilabel(app.GridLayout3);
            app.dIdTAsecEditFieldLabel.Text = 'dI/dT (A/sec):';
            app.dIdTAsecEditField = uieditfield(app.GridLayout3, 'numeric');
            app.dIdTAsecEditField.Value = 75000000;
            app.LabelEditFieldLabel = uilabel(app.GridLayout3);
            app.LabelEditFieldLabel.Text = 'Coil ID:';
            app.CoilIDEditField = uieditfield(app.GridLayout3, 'text');
            app.CoilIDEditField.Value = '1';
            app.ClearallcoilsButton = uibutton(app.GridLayout3, 'push');
            app.ClearallcoilsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallcoilsButtonPushed, true);
            app.ClearallcoilsButton.Text = 'Clear all coils';
            app.SelectCoilDropDownLabel = uilabel(app.GridLayout3);
            app.SelectCoilDropDownLabel.Text = 'Select Coil:';
            app.SelectCoilDropDown = uidropdown(app.GridLayout3);
            app.SelectCoilDropDown.Items = {'New Coil'};
            app.SelectCoilDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectCoilDropDownValueChanged, true);
            app.SelectCoilDropDown.Value = 'New Coil';
            app.DeleteButton = uibutton(app.GridLayout3, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Enable = 'off';
            app.DeleteButton.Text = 'Delete';
            app.CoilPositionLabel = uilabel(app.GridLayout3);
            app.CoilPositionLabel.Text = 'Coil Position:';
            app.CoilOrientationLabel = uilabel(app.GridLayout3);
            app.CoilOrientationLabel.Text = 'Coil Orientation:';
            app.CoilexcitationLabel = uilabel(app.GridLayout3);
            app.CoilexcitationLabel.Text = 'Coil excitation:';
            app.ThecoildisplayedinpreviewwillbeaddedtothecanvasLabel = uilabel(app.GridLayout3);
            app.ThecoildisplayedinpreviewwillbeaddedtothecanvasLabel.Text = 'The coil displayed in preview will be added to the canvas';
            app.SphereLabel = uilabel(app.GridLayout3);
            app.SphereLabel.Text = 'Sphere:';
            app.xmEditField_2Label = uilabel(app.GridLayout3);
            app.xmEditField_2Label.Text = 'x (m):';
            app.xmEditField_2 = uieditfield(app.GridLayout3, 'numeric');
            app.ymEditField_2Label = uilabel(app.GridLayout3);
            app.ymEditField_2Label.Text = 'y (m):';
            app.ymEditField_2 = uieditfield(app.GridLayout3, 'numeric');
            app.zmEditField_2Label = uilabel(app.GridLayout3);
            app.zmEditField_2Label.Text = 'z (m):';
            app.zmEditField_2 = uieditfield(app.GridLayout3, 'numeric');
            app.zmEditField_2.Value = -0.09;
            app.RadiusmEditField_2Label = uilabel(app.GridLayout3);
            app.RadiusmEditField_2Label.Text = 'Radius (m):';
            app.RadiusmEditField_2 = uieditfield(app.GridLayout3, 'numeric');
            app.RadiusmEditField_2.Value = 0.07;
            app.AddSpheretocanvasButton = uibutton(app.GridLayout3, 'push');
            app.AddSpheretocanvasButton.ButtonPushedFcn = createCallbackFcn(app, @AddSpheretocanvasButtonPushed, true);
            app.AddSpheretocanvasButton.Text = 'Add Sphere to canvas';
            app.DeleteSphereButton = uibutton(app.GridLayout3, 'push');
            app.DeleteSphereButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSphereButtonPushed, true);
            app.DeleteSphereButton.Text = 'Delete Sphere';
            app.SimulationTab = uitab(app.TabGroup2);
            app.SimulationTab.Title = '3. Simulation';
            app.GridLayout4 = uigridlayout(app.SimulationTab);
            app.ComputefieldsButton = uibutton(app.GridLayout4, 'push');
            app.ComputefieldsButton.ButtonPushedFcn = createCallbackFcn(app, @ComputefieldsButtonPushed, true);
            app.ComputefieldsButton.Text = 'Compute fields';
            app.xsizemEditFieldLabel = uilabel(app.GridLayout4);
            app.xsizemEditFieldLabel.Text = 'x size (m):';
            app.xsizemEditField = uieditfield(app.GridLayout4, 'numeric');
            app.xsizemEditField.Value = 0.2;
            app.ysizemEditFieldLabel = uilabel(app.GridLayout4);
            app.ysizemEditFieldLabel.Text = 'y size (m):';
            app.ysizemEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ysizemEditField.Value = 0.2;
            app.zsizemEditFieldLabel = uilabel(app.GridLayout4);
            app.zsizemEditFieldLabel.Text = 'z size (m):';
            app.zsizemEditField = uieditfield(app.GridLayout4, 'numeric');
            app.zsizemEditField.Value = 0.2;
            app.ZgridsEditFieldLabel = uilabel(app.GridLayout4);
            app.ZgridsEditFieldLabel.Text = 'Z grids';
            app.ZgridsEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ZgridsEditField.Value = 21;
            app.SlicealongDropDownLabel = uilabel(app.GridLayout4);
            app.SlicealongDropDownLabel.Text = 'Slice along';
            app.SlicealongDropDown = uidropdown(app.GridLayout4);
            app.SlicealongDropDown.Items = {'X', 'Y', 'Z'};
            app.SlicealongDropDown.Value = 'X';
            app.XgridsEditFieldLabel = uilabel(app.GridLayout4);
            app.XgridsEditFieldLabel.Text = 'X grids';
            app.XgridsEditField = uieditfield(app.GridLayout4, 'numeric');
            app.XgridsEditField.Value = 21;
            app.YgridsEditFieldLabel = uilabel(app.GridLayout4);
            app.YgridsEditFieldLabel.Text = 'Y grids';
            app.YgridsEditField = uieditfield(app.GridLayout4, 'numeric');
            app.YgridsEditField.Value = 21;
            app.XoffsetEditFieldLabel = uilabel(app.GridLayout4);
            app.XoffsetEditFieldLabel.Text = 'X offset';
            app.XoffsetEditField = uieditfield(app.GridLayout4, 'numeric');
            app.YoffsetEditFieldLabel = uilabel(app.GridLayout4);
            app.YoffsetEditFieldLabel.Text = 'Y offset';
            app.YoffsetEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ZoffsetEditFieldLabel = uilabel(app.GridLayout4);
            app.ZoffsetEditFieldLabel.Text = 'Z offset';
            app.ZoffsetEditField = uieditfield(app.GridLayout4, 'numeric');
            app.CoiloverlaySwitchLabel = uilabel(app.GridLayout4);
            app.CoiloverlaySwitchLabel.Text = 'Coil overlay:';
            app.CoiloverlaySwitch = uiswitch(app.GridLayout4, 'slider');
            app.VectorLabel = uilabel(app.GridLayout4);
            app.VectorLabel.Text = 'Vector:';
            app.VectorSwitch = uiswitch(app.GridLayout4, 'slider');
            app.LayerSliderLabel = uilabel(app.GridLayout4);
            app.LayerSliderLabel.Visible = 'off';
            app.LayerSliderLabel.Text = 'Layer:';
            app.LayerSlider = uislider(app.GridLayout4);
            app.LayerSlider.Limits = [0 10];
            app.LayerSlider.MajorTicks = [];
            app.LayerSlider.MajorTickLabels = {''};
            app.LayerSlider.ValueChangedFcn = createCallbackFcn(app, @LayerSliderValueChanged, true);
            app.LayerSlider.MinorTicks = [];
            app.LayerSlider.Visible = 'off';
            app.LayerSlider.Value = 4.15551839464883;
            app.Closethresholdm0fornotremovingsingularitiesEditFieldLabel = uilabel(app.GridLayout4);
            app.Closethresholdm0fornotremovingsingularitiesEditFieldLabel.Text = 'Close threshold (m): (0 for not removing singularities)';
            app.Closethresholdm0fornotremovingsingularitiesEditField = uieditfield(app.GridLayout4, 'numeric');
            app.Closethresholdm0fornotremovingsingularitiesEditField.Limits = [0 0.1];
            app.ObservationregiondimensionsLabel = uilabel(app.GridLayout4);
            app.ObservationregiondimensionsLabel.Text = 'Observation region dimensions:';
            app.ObservationregiondiscretizationLabel = uilabel(app.GridLayout4);
            app.ObservationregiondiscretizationLabel.Text = 'Observation region discretization:';
            app.CurrentlayerSpinnerLabel = uilabel(app.GridLayout4);
            app.CurrentlayerSpinnerLabel.Visible = 'off';
            app.CurrentlayerSpinnerLabel.Text = 'Current layer:';
            app.CurrentlayerSpinner = uispinner(app.GridLayout4);
            app.CurrentlayerSpinner.ValueChangedFcn = createCallbackFcn(app, @CurrentlayerSpinnerValueChanged, true);
            app.CurrentlayerSpinner.Visible = 'off';
            app.mLabel = uilabel(app.GridLayout4);
            app.mLabel.Visible = 'off';
            app.mLabel.Text = 'm';
            app.FieldComponentDropDownLabel = uilabel(app.GridLayout4);
            app.FieldComponentDropDownLabel.Enable = 'off';
            app.FieldComponentDropDownLabel.Text = 'Field Component';
            app.FieldComponentDropDown = uidropdown(app.GridLayout4);
            app.FieldComponentDropDown.Items = {'X', 'Y', 'Z', 'Magnitude'};
            app.FieldComponentDropDown.Enable = 'off';
            app.FieldComponentDropDown.Value = 'X';
            app.PlottypeDropDownLabel = uilabel(app.GridLayout4);
            app.PlottypeDropDownLabel.Text = 'Plot type:';
            app.PlottypeDropDown = uidropdown(app.GridLayout4);
            app.PlottypeDropDown.Items = {'3D', 'Filled (2D)', 'Conductive Sphere'};
            app.PlottypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PlottypeValueChanged, true);
            app.PlottypeDropDown.Value = '3D';
            app.YouwillbepromptedtosaveLabel = uilabel(app.GridLayout4);
            app.YouwillbepromptedtosaveLabel.Text = 'You will be prompted to save';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.PreviewTab = uitab(app.TabGroup);
            app.PreviewTab.Title = 'Preview';
            app.UIAxes = uiaxes(app.PreviewTab);
            title(app.UIAxes, 'Coil Preview')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CanvasTab = uitab(app.TabGroup);
            app.CanvasTab.Title = 'Canvas';
            app.UIAxes2_2 = uiaxes(app.CanvasTab);
            title(app.UIAxes2_2, 'Canvas')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.ETab = uitab(app.TabGroup);
            app.ETab.Title = 'E';
            app.UIAxes3 = uiaxes(app.ETab);
            title(app.UIAxes3, 'E, V/m')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3_2 = uiaxes(app.ETab);
            title(app.UIAxes3_2, 'CAD')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            zlabel(app.UIAxes3_2, 'Z')
            app.BTab = uitab(app.TabGroup);
            app.BTab.Title = 'B';
            app.UIAxes4 = uiaxes(app.BTab);
            title(app.UIAxes4, 'B, T')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4_2 = uiaxes(app.BTab);
            title(app.UIAxes4_2, 'CAD')
            xlabel(app.UIAxes4_2, 'X')
            ylabel(app.UIAxes4_2, 'Y')
            zlabel(app.UIAxes4_2, 'Z')
            app.InductanceTab = uitab(app.TabGroup);
            app.InductanceTab.Title = 'Inductance';
            app.ComputeInductanceButton = uibutton(app.InductanceTab, 'push');
            app.ComputeInductanceButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeInductanceButtonPushed, true);
            app.ComputeInductanceButton.Text = 'Compute Inductance';
            app.UITable = uitable(app.InductanceTab);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.InductancematrixHLabel = uilabel(app.InductanceTab);
            app.InductancematrixHLabel.Text = 'Inductance matrix (H):';
            app.BusyLampLabel = uilabel(app.RightPanel);
            app.BusyLampLabel.Text = 'Busy:';
            app.BusyLamp = uilamp(app.RightPanel);
            % Show the figure after all components are created
            app.CoilDesignerV09UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = coil_designer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CoilDesignerV09UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CoilDesignerV09UIFigure)
        end
    end
end